classdef EEG_Analysis_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                    matlab.ui.Figure
        AutoresAlanOswaldoHuertaBecerrayRosarioRiosPradoLabel  matlab.ui.control.Label
        ProcesamientoPanel         matlab.ui.container.Panel
        Preproceso                 matlab.ui.control.Button
        CoherenciaPanel            matlab.ui.container.Panel
        Cohemenu                   matlab.ui.control.DropDown
        text13                     matlab.ui.control.Label
        Coherencia_1               matlab.ui.control.Button
        PSDPanel                   matlab.ui.container.Panel
        PSDGen                     matlab.ui.control.Button
        FFTPanel                   matlab.ui.container.Panel
        FFTBandas                  matlab.ui.control.Button
        FFTmenu                    matlab.ui.control.DropDown
        text14                     matlab.ui.control.Label
        FFTransform                matlab.ui.control.Button
        CoeficientealphabetaPanel  matlab.ui.container.Panel
        MostrarestrsatravsdeltiempoButton  matlab.ui.control.Button
        ReCalc7                    matlab.ui.control.NumericEditField
        ReCalc6                    matlab.ui.control.NumericEditField
        ReCalc5                    matlab.ui.control.NumericEditField
        ReCalc4                    matlab.ui.control.NumericEditField
        ReCalc3                    matlab.ui.control.NumericEditField
        ReCalc2                    matlab.ui.control.NumericEditField
        O2Label                    matlab.ui.control.Label
        O1Label                    matlab.ui.control.Label
        T4Label                    matlab.ui.control.Label
        C4Label                    matlab.ui.control.Label
        C1Label                    matlab.ui.control.Label
        C3Label                    matlab.ui.control.Label
        T3Label                    matlab.ui.control.Label
        ReCalc                     matlab.ui.control.NumericEditField
        Calcb1                     matlab.ui.control.Button
        PREPROCESAMIENTOPanel      matlab.ui.container.Panel
        finishaxes                 matlab.ui.control.NumericEditField
        aEditFieldLabel            matlab.ui.control.Label
        startaxes                  matlab.ui.control.NumericEditField
        DeEditFieldLabel           matlab.ui.control.Label
        SegundosLabel              matlab.ui.control.Label
        AplicarSegmentoButton      matlab.ui.control.Button
        AdaptativoCheckBox         matlab.ui.control.CheckBox
        ErrorLabel                 matlab.ui.control.Label
        Save                       matlab.ui.container.Panel
        Guardar                    matlab.ui.control.Button
        uipanel2                   matlab.ui.container.Panel
        Cargar                     matlab.ui.control.Button
        text2                      matlab.ui.control.Label
        res7                       matlab.ui.control.UIAxes
        res6                       matlab.ui.control.UIAxes
        res5                       matlab.ui.control.UIAxes
        res4                       matlab.ui.control.UIAxes
        res3                       matlab.ui.control.UIAxes
        res2                       matlab.ui.control.UIAxes
        res1                       matlab.ui.control.UIAxes
        O2                         matlab.ui.control.UIAxes
        O1                         matlab.ui.control.UIAxes
        T4                         matlab.ui.control.UIAxes
        C4                         matlab.ui.control.UIAxes
        C1                         matlab.ui.control.UIAxes
        C3                         matlab.ui.control.UIAxes
        T3                         matlab.ui.control.UIAxes
    end

% Interfaz de extracción de datos EEG

%Autor: Alan Oswaldo Huerta Becerra y Rosario Rios Prado
    % F3 = C1
    % FP1 = C4
    % F4= T4
    % C4= 01
    % T4 = O2
    methods (Access = private)
        function [ady, fdy] = AFDominant(app, y, f)
                % Dominant amplitude and position in each column
                [ady,pdy] = max(y); % ady = dominant amplitud of FFT
                % fdy = Dominant frequency of FFT
                fdy = [];
                for i=1:length(pdy)
                    pos = pdy(i);
                    fd = f(pos);
                    fdy = [fdy fd];
                end
                ady=ady';
                fdy=fdy';
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function EEG_Analysis_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to EEG_Analysis (see VARARGIN)
            % Choose default command line output for EEG_Analysis
            handles.output = hObject;
            app.ErrorLabel.Visible= 'off';  
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: Cargar
        function Cargar_Callback(app, event)
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Cargar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global D1 t fs Fs Reference electrode file FileName PathName Data startaxs finishaxs S1 S2
            clc
            [FileName, PathName] = uigetfile({'*.mat';'*.tdm';'*.txt';'*.*'},'Abrir Registro');
            if isequal(FileName,0)
               disp('User selected Cancel')
            else
               disp(['User selected ', fullfile(PathName, FileName)])
            end
            file = fullfile(PathName, FileName);
            Data = importdata(fullfile(PathName, FileName));
            startaxs=((app.startaxes.Value)*256)+1; %% se suma el 1 para sincronizar el primer dato con el usuario
            finishaxs=(app.finishaxes.Value)*256;  %% se multiplica el segundo seleccionado por el numero de muestras por segundo (256)
            D1 = Data(startaxs:finishaxs,2:8);
            S1 = D1;
            S2 = Data;

            [n,m] = size(S1); %% tamaño de la muestra
            fs = 256;
            Fs= fs;
            % Sampling Frequency
            %t = (0:length(D1)-1)/Fs;          % duration time in seconds

            if startaxs>=finishaxs
                app.ErrorLabel.Visible= 'on';
            else 
                app.ErrorLabel.Visible= 'off';            
                t = (startaxs:finishaxs)/Fs;
                %t = Data(startaxs,1);   
                electrode = {'T3';'C3';'C1';'C4';'T4';'O1';'O2'};
                % Ploting FFT each complete signal from seven electrodes
                
                axes(handles.T3)
                plot(t,D1(:,1)); grid on;
                title(['Signal electrode 1: ',electrode{1}],'FontSize',9);
                xlabel('Time (sec)','FontSize',8); ylabel('Amplitude','FontSize',8)
         
                axes(handles.C3)
                plot(t,D1(:,2)); grid on;
                title(['Signal electrode 2: ',electrode{2}],'FontSize',9);
                xlabel('Time (sec)','FontSize',8); ylabel('Amplitude','FontSize',8)
                
                
                axes(handles.F3)
                plot(t,D1(:,3)); grid on;
                title(['Signal electrode 3: ',electrode{3}],'FontSize',9);
                xlabel('Time (sec)','FontSize',8); ylabel('Amplitude','FontSize',8)
              
                
                axes(handles.FP1)
                plot(t,D1(:,4)); grid on;
                title(['Signal electrode 4: ',electrode{4}],'FontSize',9);
                xlabel('Time (sec)','FontSize',8); ylabel('Amplitude','FontSize',8)
         
                
                axes(handles.F4)
                plot(t,D1(:,5)); grid on;
                title(['Signal electrode 5: ',electrode{5}],'FontSize',9);
                xlabel('Time (sec)','FontSize',8); ylabel('Amplitude','FontSize',8)
           
                
                axes(handles.C4)
                plot(t,D1(:,6)); grid on;
                title(['Signal electrode 6: ',electrode{6}],'FontSize',9);
                xlabel('Time (sec)','FontSize',8); ylabel('Amplitude','FontSize',8)
         
                
                axes(handles.T4)
                plot(t,D1(:,7)); grid on;
                title(['Signal electrode 7: ',electrode{7}],'FontSize',9);
                xlabel('Time (sec)','FontSize',8); ylabel('Amplitude','FontSize',8)
  

            end

        end

        % Button pushed function: Coherencia_1
        function Coherencia_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Coherencia (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global D1 fs f Cxy S1 S2 electrode  coherAD coherFD Cxy1 DFCxy1
            global Cxy2 DFCxy2 Cxy3 DFCxy3 Cxy4 DFCxy4 Cxy5 DFCxy5 DACxy1
            global Cxy6 DFCxy6 Cxy7 DFCxy7 DACxy2 DACxy3 DACxy4 DACxy5 DACxy6 DACxy7
            co = get(handles.Cohemenu,'Value'); %get currently selected option from menu
            %% Get the coherence beetwen signals
            % where Cxy has the size of y
            S1=D1;
            S2=D1;
            [Cxy1,f] = mscohere(S1(:,1),S2,hamming(200),[],[],fs,'mimo');
            [DACxy1,DFCxy1] = AFDominant(app, Cxy1,f);
            [Cxy2,f] = mscohere(S1(:,2),S2,hamming(200),[],[],fs,'mimo');
            [DACxy2,DFCxy2] = AFDominant(app, Cxy2,f);
            [Cxy3,f] = mscohere(S1(:,3),S2,hamming(200),[],[],fs,'mimo');
            [DACxy3,DFCxy3] = AFDominant(app, Cxy3,f);
            [Cxy4,f] = mscohere(S1(:,4),S2,hamming(200),[],[],fs,'mimo');
            [DACxy4,DFCxy4] = AFDominant(app, Cxy4,f);
            [Cxy5,f] = mscohere(S1(:,5),S2,hamming(200),[],[],fs,'mimo');
            [DACxy5,DFCxy5] = AFDominant(app, Cxy5,f);
            [Cxy6,f] = mscohere(S1(:,6),S2,hamming(200),[],[],fs,'mimo');
            [DACxy6,DFCxy6] = AFDominant(app, Cxy6,f);
            [Cxy7,f] = mscohere(S1(:,7),S2,hamming(200),[],[],fs,'mimo');
            % getting the dominant amplitude and frequency
            [DACxy7,DFCxy7] = AFDominant(app, Cxy7,f);
            
            
            if co == 1
                c = 'T3';
                cohe = Cxy1;
            elseif co == 2
                c='C3';
                cohe = Cxy2;
            elseif co == 3
                c='C1';
                cohe = Cxy3;
            elseif co == 4
               c='C4';
               cohe = Cxy4;
            elseif co == 5
                c='T4';
                cohe = Cxy5;
            elseif co == 6
               c='O1';
               cohe = Cxy6;
            elseif co == 7
                c='O2';
                cohe = Cxy7;
            end
            Cd = cohe;
            axes(handles.res1)
            plot(f(:,1),Cd(:,1))
            title(['Coherence between electrodes: ',c,' and', electrode{1}],'FontSize',8)
            xlabel('Frequency (Hz)','FontSize',7), ylabel('Amplitude','FontSize',7)
            axes(handles.res2)
            plot(f(:,1),Cd(:,2))
            title(['Coherence between electrodes: ',c,' and ',electrode{2}],'FontSize',8)
            xlabel('Frequency (Hz)','FontSize',7), ylabel('Amplitude','FontSize',7)
            axes(handles.res3)
            plot(f(:,1),Cd(:,3))
            title(['Coherence between electrodes: ',c,' and ',electrode{3}],'FontSize',8)
            xlabel('Frequency (Hz)','FontSize',7), ylabel('Amplitude','FontSize',7)
            axes(handles.res4)
            plot(f(:,1),Cd(:,4))
            title(['Coherence between electrodes: ',c,' and ',electrode{4}],'FontSize',8)
            xlabel('Frequency (Hz)','FontSize',7), ylabel('Amplitude','FontSize',7)
            axes(handles.res5)
            plot(f(:,1),Cd(:,5))
            title(['Coherence between electrodes: ',c,' and ',electrode{5}],'FontSize',8)
            xlabel('Frequency (Hz)','FontSize',7), ylabel('Amplitude','FontSize',7)
            axes(handles.res6)
            plot(f(:,1),Cd(:,6))
            title(['Coherence between electrodes: ',c,' and ',electrode{6}],'FontSize',8)
            xlabel('Frequency (Hz)','FontSize',7), ylabel('Amplitude','FontSize',7)
            axes(handles.res7)
            plot(f(:,1),Cd(:,7))
            title(['Coherence between electrodes: ',c,' and ',electrode{7}],'FontSize',8)
            xlabel('Frequency (Hz)','FontSize',7), ylabel('Amplitude','FontSize',7)
            coherAD = table(DACxy1,DACxy2,DACxy3,DACxy4,DACxy5,DACxy6,DACxy7)
            coherFD = table(DFCxy1,DFCxy2,DFCxy3,DFCxy4,DFCxy5,DFCxy6,DFCxy7)
        end

        % Button pushed function: FFTBandas
        function FFTBandas_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % % hObject    handle to FFTBandas (see GCBO)
            % % eventdata  reserved - to be defined in a future version of MATLAB
            % % handles    structure with handles and user data (see GUIDATA)
            %
            global D1 Fs fs t electrode n m DFqs DAmps
            global ADy FDy y f delta theta alpha beta ram fd ft fa fb fr
            %% Selecting bands from a complete FFT
            % delta=0-4 Hz...theta=4-8 Hz...alpha=8-12Hz...beta=12-30Hz...ram=30-45Hz
            % ram waves also called gamma waves.
            
            N=length(y);
            % Multidimensional a delta, theta, alpha, beta y ram
            % cambiar la asignacion para quitar del o delta
            for j=1:n
                del = [];
                the = [];
                alp = [];
                bet = [];
                ran = [];
                for i=1:m   % rows
                    signal = y(i,j);
                    if (f(i)>=0 && f(i)<4)
                        del = [del signal];
                    elseif (f(i)>=4 && f(i)<8)
                        the = [the signal];
                    elseif (f(i)>=8 && f(i)<12)
                        alp = [alp signal];
                    elseif (f(i)>=12 && f(i)<30)
                        bet = [bet signal];
                    elseif (f(i)>=30 && f(i)<45)
                        ran = [ran signal];
                    end
                end
                delta(:,j)=del;
                theta(:,j)=the;
                alpha(:,j)=alp;
                beta(:,j)=bet;
                ram(:,j)=ran;
            end
            
            fd = f(1:length(delta));
            ft = f(length(delta)+1:length(delta)+length(theta));
            fa = f(length(delta)+length(theta)+2:length(delta)+length(theta)+1+length(alpha));
            fb = f(length(delta)+length(theta)+3+length(alpha):length(delta)+length(theta)+2+length(alpha)+length(beta));
            fr = f(length(delta)+length(theta)+4+length(alpha)+length(beta):length(delta)+length(theta)+3+length(alpha)+length(beta)+length(ram));
            
            % Dominant amplitude and position in each column from each BAND
            [adyd,fdyd] = AFDominant(app, delta,fd);  % adyd = dominant amplitud of delta
            [adyt,fdyt] = AFDominant(app, theta,ft);  % adyt = dominant amplitud of  theta
            [adya,fdya] = AFDominant(app, alpha,fa);  % adya = dominant amplitud of alpha
            [adyb,fdyb] = AFDominant(app, beta,fb);   % adyb = dominant amplitud of beta
            [adyr,fdyr] = AFDominant(app, ram,fr);    % adyr = dominant amplitud of ram
            
            v = get(handles.FFTmenu,'Value'); %get currently selected option from menu
            if v == 1
               c='Delta';
                onda=delta;
                fo=fd;
                maxi = max(max(onda));
            elseif v == 2
               c='Theta';
                onda=theta;
                fo=ft;
                maxi = max(max(onda));
            
            elseif v == 3
                c='Alpha';
                onda=alpha;
                fo=fa;
                maxi = max(max(onda));
            
            elseif v == 4
               c='Beta';
                onda=beta;
                fo=fb;
                maxi = max(max(onda));
            
            elseif v == 5
                c='Ram';
                onda=ram;
                fo=fr;
                maxi = max(max(onda));
            end
                axes(handles.res1)
                plot(fo,onda(:,1));axis([min(fo) max(fo) 0 maxi]); grid on;
                title(['FFT Signal ',c,' 1. T3'],'FontSize',8);
                xlabel('Frequency','FontSize',7); ylabel('Amplitude','FontSize',7)
                axes(handles.res2)
                plot(fo,onda(:,2));axis([min(fo) max(fo) 0 maxi]); grid on;
                title(['FFT Signal ',c,' 2. C3'],'FontSize',8);
                xlabel('Frequency','FontSize',7); ylabel('Amplitude','FontSize',7)
                axes(handles.res3)
                plot(fo,onda(:,3));axis([min(fo) max(fo) 0 maxi]); grid on;
                title(['FFT Signal ',c,' 3. C1'],'FontSize',8);
                xlabel('Frequency','FontSize',7); ylabel('Amplitude','FontSize',7)
                axes(handles.res4)
                plot(fo,onda(:,4));axis([min(fo) max(fo) 0 maxi]); grid on;
                title(['FFT Signal ',c,' 4. C4'],'FontSize',8);
                xlabel('Frequency','FontSize',7); ylabel('Amplitude','FontSize',7)
                axes(handles.res5)
                plot(fo,onda(:,5));axis([min(fo) max(fo) 0 maxi]); grid on;
                title(['FFT Signal ',c,' 5. T4'],'FontSize',8);
                xlabel('Frequency','FontSize',7); ylabel('Amplitude','FontSize',7)
                axes(handles.res6)
                plot(fo,onda(:,6));axis([min(fo) max(fo) 0 maxi]); grid on;
                title(['FFT Signal ',c,' 6. O1'],'FontSize',8);
                xlabel('Frequency','FontSize',7); ylabel('Amplitude','FontSize',7)
                axes(handles.res7)
                plot(fo,onda(:,7));axis([min(fo) max(fo) 0 maxi]); grid on;
                title(['FFT Signal ',c,' 7. O2'],'FontSize',8);
                xlabel('Frequency','FontSize',7); ylabel('Amplitude','FontSize',7)
            
            DFqs= table(electrode,FDy,fdyd,fdyt,fdya,fdyb,fdyr)
            DAmps= table(electrode,ADy,adyd,adyt,adya,adyb,adyr)
        end

        % Value changed function: FFTmenu
        function FFTmenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to FFTmenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global delta beta theta alpha ram fa fb fd ft fr
        end

        % Button pushed function: FFTransform
        function FFTransform_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to FFTransform (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global D1 Fs fs t electrode n m
            global ADy FDy y f
            % [ y,ady,fdy,energy,erelativa,power,rd] = GetFFTBands( D1,fs )
            % delta, theta, alpha, beta, ram, fd, ft, fa, fb, fr
            % psdx,psdd,psdt,psda,psdb,psdr
            %% fft complete - read signals and get the dominant frequency and dominant amplitude
            % If D1 is a matrix, all values are calculated by column.
            % *************************************************************************
            [m,n] = size(D1);   % m-filas n-columnas
            y = abs(fft(D1));   % obtiene fft para filas y por columnas (en matriz)
            ty = (0:m-1)/fs;    % Time vector
            f = (0:m-1)*fs/m;   % frequency
            
            % ady = Dominant amplitude fdy = Dominant frequency in each column of FFT
            [ ADy,FDy ] = AFDominant(app,  y, f );
            
            % Ploting FFT each complete signal from seven electrodes
            axes(handles.res1)
            plot(f,y(:,1)); axis([0 100 0 max(y(:,2))]),grid on;
            title(['FFT Signal 1: ',electrode{1}],'FontSize',9);
            xlabel('Frequency (Hz)','FontSize',7); ylabel('Amplitude','FontSize',7)
            axes(handles.res2)
            plot(f,y(:,2)); axis([0 100 0 max(y(:,2))]),grid on;
            title(['FFT Signal 2: ',electrode{2}],'FontSize',9);
            xlabel('Frequency (Hz)','FontSize',7); ylabel('Amplitude','FontSize',7)
            axes(handles.res3)
            plot(f,y(:,3)); axis([0 100 0 max(y(:,3))]),grid on;
            title(['FFT Signal 3: ',electrode{3}],'FontSize',9);
            xlabel('Frequency (Hz)','FontSize',7); ylabel('Amplitude','FontSize',7)
            axes(handles.res4)
            plot(f,y(:,4)); axis([0 100 0 max(y(:,4))]),grid on;
            title(['FFT Signal 4: ',electrode{4}],'FontSize',9);
            xlabel('Frequency (Hz)','FontSize',7); ylabel('Amplitude','FontSize',7)
            axes(handles.res5)
            plot(f,y(:,5)); axis([0 100 0 max(y(:,5))]),grid on;
            title(['FFT Signal 5: ',electrode{5}],'FontSize',9);
            xlabel('Frequency (Hz)','FontSize',7); ylabel('Amplitude','FontSize',7)
            axes(handles.res6)
            plot(f,y(:,6)); axis([0 100 0 max(y(:,6))]),grid on;
            title(['FFT Signal 6: ',electrode{6}],'FontSize',9);
            xlabel('Frequency (Hz)','FontSize',7); ylabel('Amplitude','FontSize',7)
            axes(handles.res7)
            plot(f,y(:,7)); axis([0 100 0 max(y(:,7))]),grid on;
            title(['FFT Signal 7: ',electrode{7}],'FontSize',9);
            xlabel('Frequency (Hz)','FontSize',7); ylabel('Amplitude','FontSize',7)
        end

        % Button pushed function: Guardar
        function Guardar_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Guardar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global PathName FileName delta theta alpha beta ram fd ft fa fb fr  electrode
            global DAmps DFqs DFpsdT DApsdT DAPsd DFPsd energia erelativa power radios coherAD coherFD
            PathName;
            FileName;
            NewResult = strcat('Results_',FileName);
            file = fullfile(PathName, NewResult);
            save(file,'DFqs','DAmps','DFpsdT','DApsdT','DFPsd','DAPsd','energia','erelativa','power','radios','coherAD','coherFD');
            disp(['Saved in: ', file])
        end

        % Callback function
        function PSDBands_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % % hObject    handle to PSDBands (see GCBO)
            % % eventdata  reserved - to be defined in a future version of MATLAB
            % % handles    structure with handles and user data (see GUIDATA)
            global D1 delta theta alpha beta ram fd ft fa fb fr apsdx fpsdx DAPsd DFPsd electrode
            global psdd psdt psda psdb psdr psdx
            ps = get(handles.FFTmenu,'Value'); %get currently selected option from menu
            delta
            N = length(delta)
            psdd = 2*(1/(N)) * delta.^2;
            [adpsdd,fdpsdd] = AFDominant(app, psdd,fd);
            N = length(theta);
            psdt = 2*(1/(N)) * theta.^2;
            [adpsdt,fdpsdt] = AFDominant(app, psdt,ft);
            N = length(alpha);
            psda = 2*(1/(N)) * alpha.^2;
            [adpsda,fdpsda] = AFDominant(app, psda,fa);
            N = length(beta);
            psdb = 2*(1/(N)) * beta.^2;
            [adpsdb,fdpsdb] = AFDominant(app, psdb,fb);
            N = length(ram);
            psdr = 2*(1/(N)) * ram.^2;
            [adpsdr,fdpsdr] = AFDominant(app, psdr,fr);
            if ps == 1
               c='Delta';
                ondapsd=psdd;
                fo=fd;
                maxi = max(max(ondapsd));
            elseif ps == 2
               c='Theta';
                ondapsd=psdt;
                fo=ft;
                maxi = max(max(ondapsd));
            
            elseif ps == 3
                c='Alpha';
                ondapsd=psda;
                fo=fa;
                maxi = max(max(ondapsd));
            
            elseif ps == 4
               c='Beta';
                ondapsd=psdb;
                fo=fb;
                maxi = max(max(ondapsd));
            
            elseif ps == 5
                c='Ram';
                ondapsd=psdr;
                fo=fr;
                maxi = max(max(ondapsd));
            end
            psdxx= ondapsd
                axes(handles.res1)
                plot(fo,psdxx(:,1));axis([min(fo) max(fo) 0 maxi]); grid on;
                title(['FFT Signal ',c,' 1. T3'],'FontSize',8);
                xlabel('Frequency','FontSize',7); ylabel('Amplitude','FontSize',7)
                axes(handles.res2)
                plot(fo,psdxx(:,2));axis([min(fo) max(fo) 0 maxi]); grid on;
                title(['FFT Signal ',c,' 2. C3'],'FontSize',8);
                xlabel('Frequency','FontSize',7); ylabel('Amplitude','FontSize',7)
                axes(handles.res3)
                plot(fo,psdxx(:,3));axis([min(fo) max(fo) 0 maxi]); grid on;
                title(['FFT Signal ',c,' 3. C1'],'FontSize',8);
                xlabel('Frequency','FontSize',7); ylabel('Amplitude','FontSize',7)
                axes(handles.res4)
                plot(fo,psdxx(:,4));axis([min(fo) max(fo) 0 maxi]); grid on;
                title(['FFT Signal ',c,' 4. C4'],'FontSize',8);
                xlabel('Frequency','FontSize',7); ylabel('Amplitude','FontSize',7)
                axes(handles.res5)
                plot(fo,psdxx(:,5));axis([min(fo) max(fo) 0 maxi]); grid on;
                title(['FFT Signal ',c,' 5. T4'],'FontSize',8);
                xlabel('Frequency','FontSize',7); ylabel('Amplitude','FontSize',7)
                axes(handles.res6)
                plot(fo,psdxx(:,6));axis([min(fo) max(fo) 0 maxi]); grid on;
                title(['FFT Signal ',c,' 6. O1'],'FontSize',8);
                xlabel('Frequency','FontSize',7); ylabel('Amplitude','FontSize',7)
                axes(handles.res7)
                plot(fo,psdxx(:,7));axis([min(fo) max(fo) 0 maxi]); grid on;
                title(['FFT Signal ',c,' 7. O2'],'FontSize',8);
                xlabel('Frequency','FontSize',7); ylabel('Amplitude','FontSize',7)
            
            DAPsd = table(electrode,apsdx,adpsdd,adpsdt,adpsda,adpsdb,adpsdr)
            DFPsd = table(electrode,fpsdx,fdpsdd,fdpsdt,fdpsda,fdpsdb,fdpsdr)
        end

        % Button pushed function: PSDGen
        function PSDGen_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to C4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            global psdx D1 fs electrode apsdx fpsdx DFpsdT DApsdT pltpxx
            x= D1;
            N = length(x);
            t = (0:N-1)/fs; % Time vector
            xdft = fft(x);
            xdft = xdft(1:N/2+1,:);
            psdx = (1/(N)) * abs(xdft).^2;
            % psdx = (1/(fs*N)) * abs(xdft).^2;
            psdx(2:end-1) = 2*psdx(2:end-1);
            freq = 0:fs/length(x):fs/2;
            axes(handles.res1)
            plot(t,x); grid on;
            title('Signal','FontSize',9); xlabel('Time (sec)','FontSize',7); ylabel('Amplitude','FontSize',7)
            axes(handles.res2)
            plot(freq,abs(xdft)),grid on;
            title('FFT Signal','FontSize',9); xlabel('Frecuency (Hz)','FontSize',7); ylabel('Amplitude','FontSize',7)
            axes(handles.res3)
            plot(freq,psdx); grid on
            title('Power Spectral Density with FFT','FontSize',9);
            xlabel('Frequency (Hz)','FontSize',7); ylabel('Power (dB)','FontSize',7)
            %
            [apsdx,fpsdx] = AFDominant(app, psdx,freq);
            
            %% PSD - BURG's method
            morder = 10;
            [p,f]=pburg(x,morder,freq,fs);
            [apsdB,fpsdB] = AFDominant(app, p,freq);
            axes(handles.res4)
            plot(f,p), grid on
            title('Power Spectral Density with Burg Method','FontSize',9);
            xlabel('Frequency (Hz)','FontSize',7); ylabel('Power (dB)','FontSize',7)
            %% WELCH's METHOD
            % whithout window
            axes(handles.res5)
            pwelch(x)
            title('PSD with Welch method','FontSize',9);
            xlabel('Frequency (\omega / \pi)','FontSize',7); ylabel('Power (dB)','FontSize',7)
            % with window,
            segmentLength = 132;
            [pxx,w] = pwelch(x,segmentLength);
            [apsdW,fpsdW] = AFDominant(app, pxx,w);
            
            % figure('Name','PSD with Welch methods-Window-Overlap')
            axes(handles.res6)
            pwelch(x); grid on
            title('PSD Welch with window','FontSize',9);
            xlabel('Normalized Frequency (\omega / \pi)','FontSize',7); ylabel('Power/Frequency (dB/rad/sample)','FontSize',7)
            % subplot(5,1,4); plot(w/pi,10*log10(pxx))
            % xlabel('\omega / \pi')
            %% with window and overlap
            noverlap = 25;
            
            pxx = pwelch(x,segmentLength,noverlap);
            axes(handles.res7)
            pltpxx= 10*log10(pxx)
            plot(pltpxx), grid on
            title('PSD Welch with window and overlap','FontSize',9);
            xlabel('Frequency (Hz)','FontSize',7); ylabel('Power (dB)','FontSize',7)
            
            E= pltpxx(1:20,:);
            
            
            %% Creating the PSD's Table of a completed signal, not by bands
            % size(fpsdx)
            % size(fpsdB)
            % size(fpsdW)
            % size(apsdx)
            % size(apsdB)
            % size(apsdW)
            DFpsdT= table(electrode,fpsdx,fpsdB,fpsdW)
            DApsdT= table(electrode,apsdx,apsdB,apsdW)
        end

        % Button pushed function: Preproceso
        function Preproceso_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Preproceso (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %% %% energy
            % es multidimensional, energy contiene el general y por bandas
            % erelativa contiene las e's sólo por bandas
            % power contiene la potencia general y particular
            global psdx psdd psdt psda psdb psdr electrode
            global energia erelativa power radios
            e = sum(psdx.^2)';
            e1 = sum(psdd.^2)';
            e2 = sum(psdt.^2)';
            e3 = sum(psda.^2)';
            e4 = sum(psdb.^2)';
            e5 = sum(psdr.^2)';
            % Tabla de energías
            energia = table(electrode,e,e1,e2,e3,e4,e5)
            er1 = 100.*(e1./e);
            er2 = 100.*(e2./e);
            er3 = 100.*(e3./e);
            er4 = 100.*(e4./e);
            er5 = 100.*(e5./e);
            % Tabla de energías relativas
            erelativa = table(electrode,er1,er2,er3,er4,er5)
            
            p = (sum(psdx)./length(psdx))';
            p1 = (sum(psdd)./length(psdd))';
            p2 = (sum(psdt)./length(psdt))';
            p3 = (sum(psda)./length(psda))';
            p4 = (sum(psdb)./length(psdb))';
            p5 = (sum(psdr)./length(psdr))';
            power = table(electrode,p,p1,p2,p3,p4,p5)
            
            %% RATIOS rd = obtiene un arreglo de 25xColumns si la entrada es
            %multidimensional, o solo de 25x1 si es una entrada unidimensional.
            rd1 = [e1./e1; e1./e2; e1./e3; e1./e4; e1./e5];
            rd2 = [e2./e1; e2./e2; e2./e3; e2./e4; e2./e5];
            rd3 = [e3./e1; e3./e2; e3./e3; e3./e4; e3./e5];
            rd4 = [e4./e1; e4./e2; e4./e3; e4./e4; e4./e5];
            rd5 = [e5./e1; e5./e2; e5./e3; e5./e4; e5./e5];
            radios = table(rd1,rd2,rd3,rd4,rd5)
        end

        % Button pushed function: Calcb1
        function Calcb1ButtonPushed(app, event)
            global pltpxx Data S3T3ab S3C3ab S3C1ab S3C4ab S3T4ab S3O1ab S3O2ab S3n
            
            deltapsdww= pltpxx(1:4,:);
            thetapsdww= pltpxx(4:8,:);
            alfapsdww= pltpxx(8:12,:);
            betapsdww= pltpxx(12:30,:);
            rampsdww= pltpxx(30:100,:);
            
            T3dpsdww= deltapsdww(:,1);
            C3dpsdww= deltapsdww(:,2);
            C1dpsdww= deltapsdww(:,3);
            C4dpsdww=deltapsdww(:,4);
            T4dpsdww=deltapsdww(:,5);
            O1dpsdww=deltapsdww(:,6);
            O2dpsdww=deltapsdww(:,7);
            
            T3tpsdww= thetapsdww(:,1);
            C3tpsdww= thetapsdww(:,2);
            C1tpsdww= thetapsdww(:,3);
            C4tpsdww= thetapsdww(:,4);
            T4tpsdww= thetapsdww(:,5);
            O1tpsdww= thetapsdww(:,6);
            O2tpsdww= thetapsdww(:,7);

            T3apsdww= alfapsdww(:,1);
            C3apsdww= alfapsdww(:,2);
            C1apsdww= alfapsdww(:,3);
            C4apsdww= alfapsdww(:,4);
            T4apsdww= alfapsdww(:,5);
            O1apsdww= alfapsdww(:,6);
            O2apsdww= alfapsdww(:,7);
            
            T3bpsdww= betapsdww(:,1);
            C3bpsdww= betapsdww(:,2);
            C1bpsdww= betapsdww(:,3);
            C4bpsdww= betapsdww(:,4);
            T4bpsdww= betapsdww(:,5);
            O1bpsdww= betapsdww(:,6);
            O2bpsdww= betapsdww(:,7);
            
            T3rpsdww= rampsdww(:,1);
            C3rpsdww= rampsdww(:,2);
            C1rpsdww= rampsdww(:,3);
            C4rpsdww= rampsdww(:,4);
            T4rpsdww= rampsdww(:,5);
            O1rpsdww= rampsdww(:,6);
            O2rpsdww= rampsdww(:,7);
            
            T3b= max(T3bpsdww);
            C3b= max(C3bpsdww);
            C1b= max(C1bpsdww);
            C4b= max(C4bpsdww);
            T4b= max(T4bpsdww);
            O1b= max(O1bpsdww);
            O2b= max(O2bpsdww);
            
            T3a= max(T3apsdww);
            C3a= max(C3apsdww);
            C1a= max(C1apsdww);
            C4a= max(C4apsdww);
            T4a= max(T4apsdww);
            O1a= max(O1apsdww);
            O2a= max(O2apsdww);
            
            T3ab= T3b/T3a;
            C3ab= C3b/C3a;
            C1ab= C1b/C1a;
            C4ab= C4b/C4a;
            T4ab= T4b/T4a;
            O1ab= O1b/O1a;
            O2ab= O2b/O2a;
            
            app.ReCalc.Value= T3ab;
            app.ReCalc2.Value= C3ab;
            app.ReCalc3.Value= C1ab;
            app.ReCalc4.Value= C4ab;
            app.ReCalc5.Value= T4ab;
            app.ReCalc6.Value= O1ab;
            app.ReCalc7.Value= O2ab;
                        
            S3n=0;

            for i =1:256:(length (Data)-256)
                S3Data = Data(i:i+256,2:8);
                noverlap = 25;
                segmentLength=124;
                S3n= S3n+1;
                pxx = pwelch(S3Data,segmentLength,noverlap);
                S3pltpxx= 10*log10(pxx);
                
                S3alfapsdww= S3pltpxx(8:12,:);
                S3betapsdww= S3pltpxx(12:30,:);
                
                S3T3apsdww= S3alfapsdww(:,1);
                S3C3apsdww= S3alfapsdww(:,2);
                S3C1apsdww= S3alfapsdww(:,3);
                S3C4apsdww= S3alfapsdww(:,4);
                S3T4apsdww= S3alfapsdww(:,5);
                S3O1apsdww= S3alfapsdww(:,6);
                S3O2apsdww= S3alfapsdww(:,7);
                
                S3T3bpsdww= S3betapsdww(:,1);
                S3C3bpsdww= S3betapsdww(:,2);
                S3C1bpsdww= S3betapsdww(:,3);
                S3C4bpsdww= S3betapsdww(:,4);
                S3T4bpsdww= S3betapsdww(:,5);
                S3O1bpsdww= S3betapsdww(:,6);
                S3O2bpsdww= S3betapsdww(:,7);
        
                
                S3T3b= max(S3T3bpsdww);
                S3C3b= max(S3C3bpsdww);
                S3C1b= max(S3C1bpsdww);
                S3C4b= max(S3C4bpsdww);
                S3T4b= max(S3T4bpsdww);
                S3O1b= max(S3O1bpsdww);
                S3O2b= max(S3O2bpsdww);
                
                S3T3a= max(S3T3apsdww);
                S3C3a= max(S3C3apsdww);
                S3C1a= max(S3C1apsdww);
                S3C4a= max(S3C4apsdww);
                S3T4a= max(S3T4apsdww);
                S3O1a= max(S3O1apsdww);
                S3O2a= max(S3O2apsdww);
                
                S3T3ab(S3n)= S3T3b/S3T3a;
                S3C3ab(S3n)= S3C3b/S3C3a;
                S3C1ab(S3n)= S3C1b/S3C1a;
                S3C4ab(S3n)= S3C4b/S3C4a;
                S3T4ab(S3n)= S3T4b/S3T4a;
                S3O1ab(S3n)= S3O1b/S3O1a;
                S3O2ab(S3n)= S3O2b/S3O2a;

                %plot(pltpxx), grid on
                %title('PSD Welch with window and overlap','FontSize',9);
                %xlabel('Frequency (Hz)','FontSize',7); ylabel('Power (dB)','FontSize',7)
            end


        end

        % Value changed function: startaxes
        function startaxesValueChanged(app, event)
            global valuestart
            valuestart = app.startaxes.Value;
            
        end

        % Value changed function: finishaxes
        function finishaxesValueChanged(app, event)
            global valueend
            valueend = app.finishaxes.Value;
            
        end

        % Button pushed function: MostrarestrsatravsdeltiempoButton
        function MostrarestrsatravsdeltiempoButtonPushed(app, event)
        global S3T3ab S3C3ab S3C1ab S3C4ab S3T4ab S3O1ab S3O2ab Data S3n
           
            S3TTime(S3T3ab,S3C3ab,S3C1ab,S3C4ab,S3T4ab,S3O1ab,S3O2ab,Data, S3n) %S3 por estrés
            delete(EEG_Analysis_App)
        end

        % Button pushed function: AplicarSegmentoButton
        function AplicarSegmentoButtonPushed(app, event)
            global D1 D1F Checkvalue t fs Fs electrode  Data startaxs finishaxs S1 S2 T3filtrada C3filtrada C1filtrada C4filtrada T4filtrada O1filtrada O2filtrada
            startaxs=((app.startaxes.Value)*256)+1; %% se suma el 1 para sincronizar el primer dato con el usuario
            finishaxs=(app.finishaxes.Value)*256;  %% se multiplica el segundo seleccionado por el numero de muestras por segundo (256)


            fs = 256;
            Fs= fs;
            % Sampling Frequency
            %t = (0:length(D1)-1)/Fs;          % duration time in seconds

            if startaxs>=finishaxs
                app.ErrorLabel.Visible= 'on';
            else 
                app.ErrorLabel.Visible= 'off';            
                t = (startaxs:finishaxs)/Fs;
                %t = Data(startaxs,1);   
                electrode = {'T3';'C3';'C1';'C4';'T4';'O1';'O2'};
                if Checkvalue==1
                    D1= D1F(startaxs:finishaxs,:);% DE D1Real
                    S1 = D1;
                    S2 = Data;
                else
                    D1 = Data(startaxs:finishaxs,2:8);
                    S1 = D1;
                    S2 = Data;
                end
              
                plot(app.T3,t,D1(:,1)); grid on;
                title(['Signal electrode 1: ',electrode{1}],'FontSize',9);
                xlabel('Time (sec)','FontSize',8); ylabel('Amplitude','FontSize',8)
         
                plot(app.C3,t,D1(:,2)); grid on;
                title(['Signal electrode 2: ',electrode{2}],'FontSize',9);
                xlabel('Time (sec)','FontSize',8); ylabel('Amplitude','FontSize',8)
                
                plot(app.C1,t,D1(:,3)); grid on;
                title(['Signal electrode 3: ',electrode{3}],'FontSize',9);
                xlabel('Time (sec)','FontSize',8); ylabel('Amplitude','FontSize',8)
              
                plot(app.C4,t,D1(:,4)); grid on;
                title(['Signal electrode 4: ',electrode{4}],'FontSize',9);
                xlabel('Time (sec)','FontSize',8); ylabel('Amplitude','FontSize',8)

                plot(app.T4,t,D1(:,5)); grid on;
                title(['Signal electrode 5: ',electrode{5}],'FontSize',9);
                xlabel('Time (sec)','FontSize',8); ylabel('Amplitude','FontSize',8)

                plot(app.O1,t,D1(:,6)); grid on;
                title(['Signal electrode 6: ',electrode{6}],'FontSize',9);
                xlabel('Time (sec)','FontSize',8); ylabel('Amplitude','FontSize',8)
         
                
                plot(app.O2,t,D1(:,7)); grid on;
                title(['Signal electrode 7: ',electrode{7}],'FontSize',9);
                xlabel('Time (sec)','FontSize',8); ylabel('Amplitude','FontSize',8)
  

            end

        end

        % Value changed function: AdaptativoCheckBox
        function AdaptativoCheckBoxValueChanged(app, event)
            global D1 D1F Checkvalue t fs Fs  Data startaxs finishaxs  T3filtrada C3filtrada C1filtrada C4filtrada T4filtrada O1filtrada O2filtrada
            fc=[2 45];
            Checkvalue = app.AdaptativoCheckBox.Value;
            if Checkvalue==1

                
                [B1,A1]=butter(6,fc/(fs/2));
                T3filtrada =filtfilt(B1,A1,D1(:,1)); %%es la señal de T3 ya filtrada

                [B2,A2]=butter(6,fc/(fs/2));
                C3filtrada =filtfilt(B2,A2,D1(:,2)); %%es la señal de C3 ya filtrada

                [B3,A3]=butter(6,fc/(fs/2));
                C1filtrada =filtfilt(B3,A3,D1(:,3)); %%es la señal de C1 ya filtrada

                [B4,A4]=butter(6,fc/(fs/2));
                C4filtrada =filtfilt(B4,A4,D1(:,4)); %%es la señal de C4 ya filtrada

                [B5,A5]=butter(6,fc/(fs/2));
                T4filtrada =filtfilt(B5,A5,D1(:,5)); %%es la señal de T4 ya filtrada

                [B6,A6]=butter(6,fc/(fs/2));
                O1filtrada =filtfilt(B6,A6,D1(:,6)); %%es la señal de O1 ya filtrada

                [B7,A7]=butter(6,fc/(fs/2));
                O2filtrada =filtfilt(B7,A7,D1(:,7)); %%es la señal de O2 ya filtrada
                
                D1F= [T3filtrada ,C3filtrada, C1filtrada, C4filtrada, T4filtrada, O1filtrada, O2filtrada ];


            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'EEG_Analysis';
            app.figure1.Tag = 'figure1';
            app.T3 = uiaxes(app.figure1);
            app.T3.Tag = 'T3';
            app.C3 = uiaxes(app.figure1);
            app.C3.Tag = 'C3';
            app.C1 = uiaxes(app.figure1);
            app.C1.Tag = 'F3';
            app.C4 = uiaxes(app.figure1);
            app.C4.Tag = 'FP1';
            app.T4 = uiaxes(app.figure1);
            app.T4.Tag = 'F4';
            app.O1 = uiaxes(app.figure1);
            app.O1.Tag = 'C4';
            app.O2 = uiaxes(app.figure1);
            app.O2.Tag = 'T4';
            app.res1 = uiaxes(app.figure1);
            app.res1.Tag = 'res1';
            app.res2 = uiaxes(app.figure1);
            app.res2.Tag = 'res2';
            app.res3 = uiaxes(app.figure1);
            app.res3.Tag = 'res3';
            app.res4 = uiaxes(app.figure1);
            app.res4.Tag = 'res4';
            app.res5 = uiaxes(app.figure1);
            app.res5.Tag = 'res5';
            app.res6 = uiaxes(app.figure1);
            app.res6.Tag = 'res6';
            app.res7 = uiaxes(app.figure1);
            app.res7.Tag = 'res7';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = {'I N S T I T U T O   P O L I T É C N I C O   N A C I O N A L'; 'Unidad Profesional Interdisciplinaria en Ingeniería y Tecnologías Avanzadas (U P I I T A  -  I P N)'; 'Laboratorio de Instrumentación y Procesamiento de Señales'};
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Bases de datos';
            app.uipanel2.Tag = 'uipanel2';
            app.Cargar = uibutton(app.uipanel2, 'push');
            app.Cargar.ButtonPushedFcn = createCallbackFcn(app, @Cargar_Callback, true);
            app.Cargar.Tag = 'Cargar';
            app.Cargar.Text = 'Cargar señal';
            app.Save = uipanel(app.figure1);
            app.Save.Title = 'Guardar';
            app.Save.Tag = 'Save';
            app.Guardar = uibutton(app.Save, 'push');
            app.Guardar.ButtonPushedFcn = createCallbackFcn(app, @Guardar_Callback, true);
            app.Guardar.Tag = 'Guardar';
            app.Guardar.Text = 'Guardar .mat';
            app.ErrorLabel = uilabel(app.figure1);
            app.ErrorLabel.Text = {'!ERROR¡'; 'Inserte valores '; 'correctos'};
            app.PREPROCESAMIENTOPanel = uipanel(app.figure1);
            app.PREPROCESAMIENTOPanel.Title = 'PREPROCESAMIENTO';
            app.AdaptativoCheckBox = uicheckbox(app.PREPROCESAMIENTOPanel);
            app.AdaptativoCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdaptativoCheckBoxValueChanged, true);
            app.AdaptativoCheckBox.Text = 'Filtro Butterworth';
            app.AplicarSegmentoButton = uibutton(app.PREPROCESAMIENTOPanel, 'push');
            app.AplicarSegmentoButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarSegmentoButtonPushed, true);
            app.AplicarSegmentoButton.Text = 'Aplicar Segmento';
            app.SegundosLabel = uilabel(app.PREPROCESAMIENTOPanel);
            app.SegundosLabel.Text = 'Segundos';
            app.DeEditFieldLabel = uilabel(app.PREPROCESAMIENTOPanel);
            app.DeEditFieldLabel.Text = 'De';
            app.startaxes = uieditfield(app.PREPROCESAMIENTOPanel, 'numeric');
            app.startaxes.ValueChangedFcn = createCallbackFcn(app, @startaxesValueChanged, true);
            app.aEditFieldLabel = uilabel(app.PREPROCESAMIENTOPanel);
            app.aEditFieldLabel.Text = 'a';
            app.finishaxes = uieditfield(app.PREPROCESAMIENTOPanel, 'numeric');
            app.finishaxes.ValueChangedFcn = createCallbackFcn(app, @finishaxesValueChanged, true);
            app.CoeficientealphabetaPanel = uipanel(app.figure1);
            app.CoeficientealphabetaPanel.Title = 'Coeficiente (alpha/beta))';
            app.Calcb1 = uibutton(app.CoeficientealphabetaPanel, 'push');
            app.Calcb1.ButtonPushedFcn = createCallbackFcn(app, @Calcb1ButtonPushed, true);
            app.Calcb1.Text = 'Calcular (beta/alpha)';
            app.ReCalc = uieditfield(app.CoeficientealphabetaPanel, 'numeric');
            app.ReCalc.Editable = 'off';
            app.T3Label = uilabel(app.CoeficientealphabetaPanel);
            app.T3Label.Text = 'T3';
            app.C3Label = uilabel(app.CoeficientealphabetaPanel);
            app.C3Label.Text = 'C3';
            app.C1Label = uilabel(app.CoeficientealphabetaPanel);
            app.C1Label.Text = 'C1';
            app.C4Label = uilabel(app.CoeficientealphabetaPanel);
            app.C4Label.Text = 'C4';
            app.T4Label = uilabel(app.CoeficientealphabetaPanel);
            app.T4Label.Text = 'T4';
            app.O1Label = uilabel(app.CoeficientealphabetaPanel);
            app.O1Label.Text = 'O1';
            app.O2Label = uilabel(app.CoeficientealphabetaPanel);
            app.O2Label.Text = 'O2';
            app.ReCalc2 = uieditfield(app.CoeficientealphabetaPanel, 'numeric');
            app.ReCalc2.Editable = 'off';
            app.ReCalc3 = uieditfield(app.CoeficientealphabetaPanel, 'numeric');
            app.ReCalc3.Editable = 'off';
            app.ReCalc4 = uieditfield(app.CoeficientealphabetaPanel, 'numeric');
            app.ReCalc4.Editable = 'off';
            app.ReCalc5 = uieditfield(app.CoeficientealphabetaPanel, 'numeric');
            app.ReCalc5.Editable = 'off';
            app.ReCalc6 = uieditfield(app.CoeficientealphabetaPanel, 'numeric');
            app.ReCalc6.Editable = 'off';
            app.ReCalc7 = uieditfield(app.CoeficientealphabetaPanel, 'numeric');
            app.ReCalc7.Editable = 'off';
            app.MostrarestrsatravsdeltiempoButton = uibutton(app.CoeficientealphabetaPanel, 'push');
            app.MostrarestrsatravsdeltiempoButton.ButtonPushedFcn = createCallbackFcn(app, @MostrarestrsatravsdeltiempoButtonPushed, true);
            app.MostrarestrsatravsdeltiempoButton.Text = {'Mostrar estrés a'; ' través del tiempo'};
            app.ProcesamientoPanel = uipanel(app.figure1);
            app.ProcesamientoPanel.Title = 'Procesamiento';
            app.FFTPanel = uipanel(app.ProcesamientoPanel);
            app.FFTPanel.Title = 'FFT';
            app.FFTransform = uibutton(app.FFTPanel, 'push');
            app.FFTransform.ButtonPushedFcn = createCallbackFcn(app, @FFTransform_Callback, true);
            app.FFTransform.Tag = 'FFTransform';
            app.FFTransform.Text = 'Aplicar FFT';
            app.text14 = uilabel(app.FFTPanel);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Banda';
            app.FFTmenu = uidropdown(app.FFTPanel);
            app.FFTmenu.Items = {'Delta', 'Theta', 'Alpha', 'Beta', 'Ram'};
            app.FFTmenu.ValueChangedFcn = createCallbackFcn(app, @FFTmenu_Callback, true);
            app.FFTmenu.Tag = 'FFTmenu';
            app.FFTmenu.Value = 'Delta';
            app.FFTBandas = uibutton(app.FFTPanel, 'push');
            app.FFTBandas.ButtonPushedFcn = createCallbackFcn(app, @FFTBandas_Callback, true);
            app.FFTBandas.Tag = 'FFTBandas';
            app.FFTBandas.Text = 'FFT por bandas';
            app.PSDPanel = uipanel(app.ProcesamientoPanel);
            app.PSDPanel.Title = 'PSD';
            app.PSDGen = uibutton(app.PSDPanel, 'push');
            app.PSDGen.ButtonPushedFcn = createCallbackFcn(app, @PSDGen_Callback, true);
            app.PSDGen.Tag = 'PSDGen';
            app.PSDGen.Text = 'Obtener PSD genérica';
            app.CoherenciaPanel = uipanel(app.ProcesamientoPanel);
            app.CoherenciaPanel.Title = 'Coherencia';
            app.Coherencia_1 = uibutton(app.CoherenciaPanel, 'push');
            app.Coherencia_1.ButtonPushedFcn = createCallbackFcn(app, @Coherencia_Callback, true);
            app.Coherencia_1.Tag = 'Coherencia_1';
            app.Coherencia_1.Text = 'Obtener gráficas';
            app.text13 = uilabel(app.CoherenciaPanel);
            app.text13.Tag = 'text13';
            app.text13.Text = 'N° electrodo';
            app.Cohemenu = uidropdown(app.CoherenciaPanel);
            app.Cohemenu.Items = {'T3', 'C3', 'C1', 'C4', 'T4', 'O1', 'O2'};
            app.Cohemenu.Tag = 'Cohemenu';
            app.Cohemenu.Value = 'T3';
            app.Preproceso = uibutton(app.ProcesamientoPanel, 'push');
            app.Preproceso.ButtonPushedFcn = createCallbackFcn(app, @Preproceso_Callback, true);
            app.Preproceso.Tag = 'Preproceso';
            app.Preproceso.Text = 'E, Erelativa, P, Radios';
            app.AutoresAlanOswaldoHuertaBecerrayRosarioRiosPradoLabel = uilabel(app.figure1);
            app.AutoresAlanOswaldoHuertaBecerrayRosarioRiosPradoLabel.Text = 'Autores: Alan Oswaldo Huerta Becerra y Rosario Rios Prado';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEG_Analysis_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)EEG_Analysis_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end