classdef S3TTime < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        S3Vent            matlab.ui.Figure
        Image2            matlab.ui.control.Image
        SegmButton        matlab.ui.control.Button
        SegundosLabel     matlab.ui.control.Label
        FinishField       matlab.ui.control.NumericEditField
        aEditFieldLabel   matlab.ui.control.Label
        StartField        matlab.ui.control.NumericEditField
        DeEditFieldLabel  matlab.ui.control.Label
        UIAxesO2          matlab.ui.control.UIAxes
        UIAxesO1          matlab.ui.control.UIAxes
        UIAxesT4          matlab.ui.control.UIAxes
        UIAxesC4          matlab.ui.control.UIAxes
        UIAxesC1          matlab.ui.control.UIAxes
        UIAxesC3          matlab.ui.control.UIAxes
        UIAxesT3          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, S3T3ab, S3C3ab, S3C1ab, S3C4ab, S3T4ab, S3O1ab, S3O2ab, Data, S3n)
            global S3n Tstart Tfinish T3 C3 C1 C4 T4 O1 O2 t yT3 yC3 yC1 yC4 yT4 yO1 yO2 x1
            Tstart=1;
            Tfinish= S3n;
            t=Tstart:1:Tfinish;

            T3=S3T3ab;
            C3=S3C3ab;
            C1= S3C1ab;
            C4= S3C4ab;
            T4=S3T4ab;
            O1=S3O1ab;
            O2=S3O1ab;

            x1= t;
            yT3= T3;
            length(yT3);
            plot(app.UIAxesT3, x1 ,yT3);


            yC3= C3;
            length(yC3);
            plot(app.UIAxesC3, x1 ,yC3)
  
            yC1= C1;
            length(yC1);
            plot(app.UIAxesC1, x1 ,yC1)


            yC4= C4;
            length(yC4);
            plot(app.UIAxesC4, x1 ,yC4);
      

            yT4= T4;
            length(yT4);
            plot(app.UIAxesT4, x1 ,yT4);
   

            yO1= O1;
            length(yO1);
            plot(app.UIAxesO1, x1 ,yO1);


            yO2= O2;
            length(yO2);
            plot(app.UIAxesO2, x1 ,yO2);

        end

        % Close request function: S3Vent
        function S3VentCloseRequest(app, event)
            delete(app)
            EEG_Analysis_App
        end

        % Callback function
        function SegmCheckValueChanged(app, event)

        end

        % Button pushed function: SegmButton
        function SegmButtonPushed(app, event)
            global Tstart Tfinish  T3 C3 C1 C4 T4 O1 O2 Data t yT3 yC3 yC1 yC4 yT4 yO1 yO2 x1 


            Tstart = app.StartField.Value 
            Tfinish =app.FinishField.Value
            x1= t;
            yT3= T3;
            length(yT3);
            plot(app.UIAxesT3, x1 ,yT3);
            xlim(app.UIAxesT3,[Tstart Tfinish]);


            yC3= C3;
            length(yC3);
            plot(app.UIAxesC3, x1 ,yC3)
            xlim(app.UIAxesC3,[Tstart Tfinish]);
  
            yC1= C1;
            length(yC1);
            plot(app.UIAxesC1, x1 ,yC1)
            xlim(app.UIAxesC1,[Tstart Tfinish]);


            yC4= C4;
            length(yC4);
            plot(app.UIAxesC4, x1 ,yC4);
            xlim(app.UIAxesC4,[Tstart Tfinish]);
      

            yT4= T4;
            length(yT4);
            plot(app.UIAxesT4, x1 ,yT4);
            xlim(app.UIAxesT4,[Tstart Tfinish]);
   

            yO1= O1;
            length(yO1);
            plot(app.UIAxesO1, x1 ,yO1);
            xlim(app.UIAxesO1,[Tstart Tfinish]);


            yO2= O2;
            length(yO2);
            plot(app.UIAxesO2, x1 ,yO2);
            xlim(app.UIAxesO2,[Tstart Tfinish]);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.S3Vent = uifigure('Visible', 'off');
            app.S3Vent.Name = 'MATLAB App';
            app.S3Vent.CloseRequestFcn = createCallbackFcn(app, @S3VentCloseRequest, true);
            app.UIAxesT3 = uiaxes(app.S3Vent);
            title(app.UIAxesT3, 'T3')
            xlabel(app.UIAxesT3, 'Tiempo (s)')
            ylabel(app.UIAxesT3, 'Coeficiente (beta/alpha)')
            zlabel(app.UIAxesT3, 'Z')
            app.UIAxesC3 = uiaxes(app.S3Vent);
            title(app.UIAxesC3, 'C3')
            xlabel(app.UIAxesC3, 'Tiempo (s)')
            ylabel(app.UIAxesC3, 'Coef. (beta/alpha)')
            zlabel(app.UIAxesC3, 'Z')
            app.UIAxesC1 = uiaxes(app.S3Vent);
            title(app.UIAxesC1, 'C4')
            xlabel(app.UIAxesC1, 'Tiempo (s)')
            ylabel(app.UIAxesC1, 'Coeficiente (beta/alpha)')
            zlabel(app.UIAxesC1, 'Z')
            app.UIAxesC4 = uiaxes(app.S3Vent);
            title(app.UIAxesC4, 'T4')
            xlabel(app.UIAxesC4, 'Tiempo (s)')
            ylabel(app.UIAxesC4, 'Coef. (beta/alpha)')
            zlabel(app.UIAxesC4, 'Z')
            app.UIAxesT4 = uiaxes(app.S3Vent);
            title(app.UIAxesT4, 'O2')
            xlabel(app.UIAxesT4, 'Tiempo (s)')
            ylabel(app.UIAxesT4, 'Coeficiente (beta/alpha)')
            zlabel(app.UIAxesT4, 'Z')
            app.UIAxesO1 = uiaxes(app.S3Vent);
            title(app.UIAxesO1, 'C1')
            xlabel(app.UIAxesO1, 'Tiempo (s)')
            ylabel(app.UIAxesO1, 'Coef. (beta/alpha)')
            zlabel(app.UIAxesO1, 'Z')
            app.UIAxesO2 = uiaxes(app.S3Vent);
            title(app.UIAxesO2, 'O1')
            xlabel(app.UIAxesO2, 'Tiempo (s)')
            ylabel(app.UIAxesO2, 'Coef. (beta/alpha)')
            zlabel(app.UIAxesO2, 'Z')
            app.DeEditFieldLabel = uilabel(app.S3Vent);
            app.DeEditFieldLabel.Text = 'De';
            app.StartField = uieditfield(app.S3Vent, 'numeric');
            app.aEditFieldLabel = uilabel(app.S3Vent);
            app.aEditFieldLabel.Text = 'a';
            app.FinishField = uieditfield(app.S3Vent, 'numeric');
            app.SegundosLabel = uilabel(app.S3Vent);
            app.SegundosLabel.Text = 'Segundos';
            app.SegmButton = uibutton(app.S3Vent, 'push');
            app.SegmButton.ButtonPushedFcn = createCallbackFcn(app, @SegmButtonPushed, true);
            app.SegmButton.Text = 'Aplicar';
            app.Image2 = uiimage(app.S3Vent);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'logo.png');
            % Show the figure after all components are created
            app.S3Vent.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = S3TTime(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.S3Vent)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.S3Vent)
        end
    end
end